classdef GuiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SacarMatriculaTab               matlab.ui.container.Tab
        GirarimagenButton               matlab.ui.control.Button
        TextomatriculaTextArea          matlab.ui.control.TextArea
        TextomatriculaTextAreaLabel     matlab.ui.control.Label
        SeleccionarimagenButton         matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        EjecutarButton                  matlab.ui.control.Button
        MatrculadetectadaPanel          matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        SacarmatrculadecochesLabel      matlab.ui.control.Label
        EntrenarAlexNetTab              matlab.ui.container.Tab
        VOLVERButton_2                  matlab.ui.control.Button
        PLAYButton_2                    matlab.ui.control.Button
        RESETButton_2                   matlab.ui.control.Button
        VerboseEditField_2              matlab.ui.control.EditField
        VerboseEditField_2Label         matlab.ui.control.Label
        ValidationPatienceEditField_2   matlab.ui.control.EditField
        ValidationPatienceEditField_2Label  matlab.ui.control.Label
        ValidationFrequencyEditField_2  matlab.ui.control.NumericEditField
        ValidationFrequencyEditField_2Label  matlab.ui.control.Label
        ValidationDataEditField_2       matlab.ui.control.EditField
        ValidationDataEditField_2Label  matlab.ui.control.Label
        InitialLearnRateEditField_2     matlab.ui.control.NumericEditField
        InitialLearnRateEditField_2Label  matlab.ui.control.Label
        MaxEpochsEditField_2            matlab.ui.control.NumericEditField
        MaxEpochsEditField_2Label       matlab.ui.control.Label
        EntrenarredAlexNetLabel         matlab.ui.control.Label
        MiniBachtSizeEditField          matlab.ui.control.NumericEditField
        MiniBachtSizeEditFieldLabel     matlab.ui.control.Label
        MtododeoptimizacinDropDown      matlab.ui.control.DropDown
        MtododeoptimizacinDropDownLabel  matlab.ui.control.Label
        EntrenarGoogleNetTab            matlab.ui.container.Tab
        VOLVERButton                    matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        PLAYButton                      matlab.ui.control.Button
        AjustesPanel                    matlab.ui.container.Panel
        MtododeoptimizacinDropDown_2    matlab.ui.control.DropDown
        MtododeoptimizacinDropDown_2Label  matlab.ui.control.Label
        VerboseEditField                matlab.ui.control.EditField
        VerboseEditFieldLabel           matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.EditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        ValidationDataEditField         matlab.ui.control.EditField
        ValidationDataEditFieldLabel    matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        EntrenarredGoogleNetLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageFile; % Description
        ImagenSeleccionada = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            if app.ImagenSeleccionada
                [IMat, textoMat] = GetMatricula(app.ImageFile);
                app.TextomatriculaTextArea.Value = textoMat;
                app.Image3.ImageSource = IMat;
                %{
                hold(app.ContainerVideo, 'on'); 
                text(XSupDcha+100,YSupDcha,texto,'Color','y','FontSize',10,'FontWeight','bold')
line([XSupIzda,XSupDcha],[YSupIzda,YSupDcha],'LineWidth',2,'Color',color)
line([XSupIzda,XInfIzda],[YSupIzda,YInfIzda],'LineWidth',2,'Color',color)
line([XSupDcha,XInfDcha],[YSupDcha,YInfDcha],'LineWidth',2,'Color',color)
line([XInfIzda,XInfDcha],[YInfIzda,YInfDcha],'LineWidth',2,'Color',color)
hold(app.ContainerVideo, 'off');
                %}
            else
                msgbox("No hay ninguna imagen seleccionada.");
            end
        end

        % Button pushed function: SeleccionarimagenButton
        function SeleccionarimagenButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.ImageFile = imread(fullname);
           app.Image.ImageSource = app.ImageFile;
           app.ImagenSeleccionada = true;
           %ImagenEntrada.ImageSource = app.ImageFile;
           %imagesc(GuiApp.ImagenEntrada.so, ImageFile);
        end

        % Button pushed function: GirarimagenButton
        function GirarimagenButtonPushed(app, event)
            app.ImageFile = imrotate(app.ImageFile,-90,'bilinear');
            app.Image.ImageSource = app.ImageFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SacarMatriculaTab = uitab(app.TabGroup);
            app.SacarMatriculaTab.Title = 'Sacar Matricula';
            app.SacarmatrculadecochesLabel = uilabel(app.SacarMatriculaTab);
            app.SacarmatrculadecochesLabel.Text = 'Sacar matrícula de coches';
            app.MatrculadetectadaPanel = uipanel(app.SacarMatriculaTab);
            app.MatrculadetectadaPanel.Title = 'Matrícula detectada';
            app.Image3 = uiimage(app.MatrculadetectadaPanel);
            app.EjecutarButton = uibutton(app.SacarMatriculaTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Image = uiimage(app.SacarMatriculaTab);
            app.Image.Tag = 'ImagenEntrada';
            app.SeleccionarimagenButton = uibutton(app.SacarMatriculaTab, 'push');
            app.SeleccionarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButtonPushed, true);
            app.SeleccionarimagenButton.Text = 'Seleccionar imagen';
            app.TextomatriculaTextAreaLabel = uilabel(app.SacarMatriculaTab);
            app.TextomatriculaTextAreaLabel.Text = 'Texto matricula';
            app.TextomatriculaTextArea = uitextarea(app.SacarMatriculaTab);
            app.GirarimagenButton = uibutton(app.SacarMatriculaTab, 'push');
            app.GirarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @GirarimagenButtonPushed, true);
            app.GirarimagenButton.Text = 'Girar imagen';
            app.EntrenarAlexNetTab = uitab(app.TabGroup);
            app.EntrenarAlexNetTab.Title = 'Entrenar AlexNet';
            app.MtododeoptimizacinDropDownLabel = uilabel(app.EntrenarAlexNetTab);
            app.MtododeoptimizacinDropDownLabel.Text = 'Método de optimización';
            app.MtododeoptimizacinDropDown = uidropdown(app.EntrenarAlexNetTab);
            app.MtododeoptimizacinDropDown.Items = {'sgdm', 'adam'};
            app.MtododeoptimizacinDropDown.Value = 'sgdm';
            app.MiniBachtSizeEditFieldLabel = uilabel(app.EntrenarAlexNetTab);
            app.MiniBachtSizeEditFieldLabel.Text = 'MiniBachtSize';
            app.MiniBachtSizeEditField = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.MiniBachtSizeEditField.Limits = [0 Inf];
            app.MiniBachtSizeEditField.Value = 10;
            app.EntrenarredAlexNetLabel = uilabel(app.EntrenarAlexNetTab);
            app.EntrenarredAlexNetLabel.Text = 'Entrenar red AlexNet';
            app.MaxEpochsEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.MaxEpochsEditField_2Label.Text = 'MaxEpochs';
            app.MaxEpochsEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.InitialLearnRateEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.InitialLearnRateEditField_2Label.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.ValidationDataEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationDataEditField_2Label.Text = 'ValidationData';
            app.ValidationDataEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.ValidationFrequencyEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationFrequencyEditField_2Label.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'numeric');
            app.ValidationPatienceEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.ValidationPatienceEditField_2Label.Text = 'ValidationPatience';
            app.ValidationPatienceEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.VerboseEditField_2Label = uilabel(app.EntrenarAlexNetTab);
            app.VerboseEditField_2Label.Text = 'Verbose';
            app.VerboseEditField_2 = uieditfield(app.EntrenarAlexNetTab, 'text');
            app.RESETButton_2 = uibutton(app.EntrenarAlexNetTab, 'push');
            app.RESETButton_2.Text = 'RESET';
            app.PLAYButton_2 = uibutton(app.EntrenarAlexNetTab, 'push');
            app.PLAYButton_2.Text = 'PLAY';
            app.VOLVERButton_2 = uibutton(app.EntrenarAlexNetTab, 'push');
            app.VOLVERButton_2.Text = 'VOLVER';
            app.EntrenarGoogleNetTab = uitab(app.TabGroup);
            app.EntrenarGoogleNetTab.Title = 'Entrenar GoogleNet';
            app.EntrenarredGoogleNetLabel = uilabel(app.EntrenarGoogleNetTab);
            app.EntrenarredGoogleNetLabel.Text = {'Entrenar red GoogleNet'; ''};
            app.AjustesPanel = uipanel(app.EntrenarGoogleNetTab);
            app.AjustesPanel.Title = 'Ajustes';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.AjustesPanel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.MaxEpochsEditFieldLabel = uilabel(app.AjustesPanel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.InitialLearnRateEditFieldLabel = uilabel(app.AjustesPanel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.ValidationDataEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationDataEditFieldLabel.Text = 'ValidationData';
            app.ValidationDataEditField = uieditfield(app.AjustesPanel, 'text');
            app.ValidationFrequencyEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.AjustesPanel, 'numeric');
            app.ValidationPatienceEditFieldLabel = uilabel(app.AjustesPanel);
            app.ValidationPatienceEditFieldLabel.Text = 'ValidationPatience';
            app.ValidationPatienceEditField = uieditfield(app.AjustesPanel, 'text');
            app.VerboseEditFieldLabel = uilabel(app.AjustesPanel);
            app.VerboseEditFieldLabel.Text = 'Verbose';
            app.VerboseEditField = uieditfield(app.AjustesPanel, 'text');
            app.MtododeoptimizacinDropDown_2Label = uilabel(app.AjustesPanel);
            app.MtododeoptimizacinDropDown_2Label.Text = 'Método de optimización';
            app.MtododeoptimizacinDropDown_2 = uidropdown(app.AjustesPanel);
            app.MtododeoptimizacinDropDown_2.Items = {'sgdm', 'adam'};
            app.MtododeoptimizacinDropDown_2.Value = 'sgdm';
            app.PLAYButton = uibutton(app.EntrenarGoogleNetTab, 'push');
            app.PLAYButton.Text = 'PLAY';
            app.RESETButton = uibutton(app.EntrenarGoogleNetTab, 'push');
            app.RESETButton.Text = 'RESET';
            app.VOLVERButton = uibutton(app.EntrenarGoogleNetTab, 'push');
            app.VOLVERButton.Text = 'VOLVER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end